classdef nowe_gui_optymalizacja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WybierzplikButton               matlab.ui.control.StateButton
        WprowadmodulyEditFieldLabel     matlab.ui.control.Label
        WprowadmodulyEditField          matlab.ui.control.EditField
        STARTButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Optymalizacja                   matlab.ui.control.Label
        WprowadpocztkowewartociEditFieldLabel  matlab.ui.control.Label
        WprowadpocztkowewartociEditField  matlab.ui.control.EditField
        WartobduLabel                   matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        WartociparametrwLabel           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TemperaturaCLabel               matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        WynikiLabel                     matlab.ui.control.Label
        OptymalizacjaautomatycznaLabel  matlab.ui.control.Label
        WprowaddolnegraniceEditFieldLabel  matlab.ui.control.Label
        WprowaddolnegraniceEditField    matlab.ui.control.EditField
        WybrzakresuczstotliwociLabel    matlab.ui.control.Label
        ZakresczstotliwociLabel         matlab.ui.control.Label
        NumerpomiaruSliderLabel         matlab.ui.control.Label
        NumerpomiaruSlider              matlab.ui.control.Slider
        ZapiszwynikdlawybranegopomiaruButton  matlab.ui.control.Button
        ZapiszwszystkoButton            matlab.ui.control.Button
        MinSliderLabel                  matlab.ui.control.Label
        MinSlider                       matlab.ui.control.Slider
        MaxSliderLabel                  matlab.ui.control.Label
        MaxSlider                       matlab.ui.control.Slider
        OKButton                        matlab.ui.control.Button
        MinManual                       matlab.ui.control.NumericEditField
        MaxManual                       matlab.ui.control.NumericEditField
        WprowadgrnegraniceEditFieldLabel  matlab.ui.control.Label
        WprowadgrnegraniceEditField     matlab.ui.control.EditField
        Switch                          matlab.ui.control.Switch
    end

    
    properties (Access = private)
        nazwa_pliku;
        sciezka_pliku;
        wynik;
        onoff = 0;
        czestotliwosci;
        liczba_pomiarow;
        min_czestotliwosc;
        max_czestotliwosc;
        
    end
    

    methods (Access = private)

        % Value changed function: WybierzplikButton
        function WybierzplikButtonValueChanged(app, event)
            value = app.WybierzplikButton.Value;
            [app.nazwa_pliku,app.sciezka_pliku] = uigetfile('*.LCR');
            figure(app.UIFigure);
            app.Optymalizacja.Text = app.nazwa_pliku;
            
            [app.wynik, app.liczba_pomiarow] = wczytaj_LRC(strcat(app.sciezka_pliku,app.nazwa_pliku));
            app.NumerpomiaruSlider.Limits = [1 app.liczba_pomiarow];       
            
            app.czestotliwosci = wczytaj_czestotliwosci(strcat(app.sciezka_pliku,app.nazwa_pliku));
            app.MaxSlider.Limits = [log10(app.czestotliwosci(1)) log10(app.czestotliwosci(end))];
            app.MinSlider.Limits = [log10(app.czestotliwosci(1)) log10(app.czestotliwosci(end))];

        end

        % Callback function
        function TextAreaValueChanged(app, event)
            
        end

        % Value changed function: WprowadmodulyEditField
        function WprowadmodulyEditFieldValueChanged(app, event)

        end

        % Callback function
        function WprowadpocztkowewartociEditFieldValueChanged(app, event)
            
        end

        % Value changed function: WprowadpocztkowewartociEditField
        function WprowadpocztkowewartociEditFieldValueChanged2(app, event)
            value = app.WprowadpocztkowewartociEditField.Value;
%             parametry = str2double(strsplit(value,','));
        end

        % Callback function
        function STARTButtonPushed(app, event)
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed2(app, event)
%             app.sciezka_pliku = app.Optymalizacja.Text;
            moduly = app.WprowadmodulyEditField.Value;
            parametry = str2double(strsplit(app.WprowadpocztkowewartociEditField.Value,','));
            
            app.wynik = main(strcat(app.sciezka_pliku,app.nazwa_pliku), moduly, parametry);

            app.onoff = 1;
            
%             app.WybierznumerpomiaruDropDown.Items = strsplit(num2str([1:length(app.wynik)]));
           
        end

        % Value changed function: NumerpomiaruSlider
        function NumerpomiaruSliderValueChanged(app, event)
            value = app.NumerpomiaruSlider.Value;
            wynik_aktualny = app.wynik(round(value));
            disableDefaultInteractivity(app.UIAxes);
            
            if app.onoff == 0
                plot(app.UIAxes, real(wynik_aktualny.imp), -imag(wynik_aktualny.imp), 'r.');
                app.Label.Text = num2str(wynik_aktualny.temperature);

            else
                plot(app.UIAxes, real(wynik_aktualny.impedancja.Z_exp), -imag(wynik_aktualny.impedancja.Z_exp), 'r.', real(wynik_aktualny.impedancja.Z_sym), -imag(wynik_aktualny.impedancja.Z_sym), 'b-')
                app.Label_3.Text = num2str(wynik_aktualny.blad, 5);
                app.Label_2.Text = num2str(wynik_aktualny.wektor);
                app.Label.Text = num2str(wynik_aktualny.temperatura);
                
            end
            
            app.NumerpomiaruSlider.Value = round(value);
            
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            value = app.MinSlider.Value;
            if value > app.MaxSlider.Value
                app.MinSlider.Value = app.MaxSlider.Value;
            end
            
            app.MinManual.Value = value;
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            value = app.MaxSlider.Value;
            if value < app.MinSlider.Value
                app.MaxSlider.Value = app.MinSlider.Value;
            end
                
            app.MaxManual.Value = value;
        end

        % Value changed function: MinManual
        function MinManualValueChanged(app, event)
            value = app.MinManual.Value;
            app.min_czestotliwosc = 10^value;
            app.MinSlider.Value = value;
        end

        % Value changed function: MaxManual
        function MaxManualValueChanged(app, event)
            value = app.MaxManual.Value;
            app.max_czestotliwosc = 10^value;
            app.MaxSlider.Value = value;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.wynik(app.NumerpomiaruSlider.Value) = uwzglednij_zakres_czestotliwosci(app.wynik, app.NumerpomiaruSlider.Value, app.liczba_pomiarow, app.MinManual.Value, app.MaxManual.Value);
            optymalizuj_ponownie(app.wynik, app.NumerpomiaruSlider.Value, app.WprowadmodulyEditField.Value);
          
            wynik_aktualny = app.wynik(app.NumerpomiaruSlider.Value);
            app.Label_3.Text = num2str(wynik_aktualny.blad, 5);
            app.Label_2.Text = num2str(wynik_aktualny.wektor);


            disp('tekst')
            size(app.wynik(app.NumerpomiaruSlider.Value).impedancja.Z_exp)
            

            plot(app.UIAxes, real(wynik_aktualny.impedancja.Z_exp), -imag(wynik_aktualny.impedancja.Z_exp), 'r.', real(wynik_aktualny.impedancja.Z_sym), -imag(wynik_aktualny.impedancja.Z_sym), 'bo')

        end

        % Button pushed function: ZapiszwszystkoButton
        function ZapiszwszystkoButtonPushed(app, event)
            nazwa_pliku_wynik = uiputfile;
            uisave(app.wynik, nazwa_pliku_wynik);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WybierzplikButton = uibutton(app.UIFigure, 'state');
            app.WybierzplikButton.ValueChangedFcn = createCallbackFcn(app, @WybierzplikButtonValueChanged, true);
            app.WybierzplikButton.Text = 'Wybierz plik';
            app.WprowadmodulyEditFieldLabel = uilabel(app.UIFigure);
            app.WprowadmodulyEditFieldLabel.Text = 'Wprowadź moduly';
            app.WprowadmodulyEditField = uieditfield(app.UIFigure, 'text');
            app.WprowadmodulyEditField.ValueChangedFcn = createCallbackFcn(app, @WprowadmodulyEditFieldValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed2, true);
            app.STARTButton.Text = 'START';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'-Z''''(Z'')'; ''})
            xlabel(app.UIAxes, 'Z''')
            ylabel(app.UIAxes, '-Z''''')
            app.Optymalizacja = uilabel(app.UIFigure);
            app.Optymalizacja.Text = '';
            app.WprowadpocztkowewartociEditFieldLabel = uilabel(app.UIFigure);
            app.WprowadpocztkowewartociEditFieldLabel.Text = 'Wprowadź początkowe wartości';
            app.WprowadpocztkowewartociEditField = uieditfield(app.UIFigure, 'text');
            app.WprowadpocztkowewartociEditField.ValueChangedFcn = createCallbackFcn(app, @WprowadpocztkowewartociEditFieldValueChanged2, true);
            app.WartobduLabel = uilabel(app.UIFigure);
            app.WartobduLabel.Text = 'Wartość błędu';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.WartociparametrwLabel = uilabel(app.UIFigure);
            app.WartociparametrwLabel.Text = 'Wartości parametrów';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TemperaturaCLabel = uilabel(app.UIFigure);
            app.TemperaturaCLabel.Text = 'Temperatura [C] ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.WynikiLabel = uilabel(app.UIFigure);
            app.WynikiLabel.Text = 'Wyniki';
            app.OptymalizacjaautomatycznaLabel = uilabel(app.UIFigure);
            app.OptymalizacjaautomatycznaLabel.Text = 'Optymalizacja automatyczna';
            app.WprowaddolnegraniceEditFieldLabel = uilabel(app.UIFigure);
            app.WprowaddolnegraniceEditFieldLabel.Text = 'Wprowadź dolne granice';
            app.WprowaddolnegraniceEditField = uieditfield(app.UIFigure, 'text');
            app.WybrzakresuczstotliwociLabel = uilabel(app.UIFigure);
            app.WybrzakresuczstotliwociLabel.Text = 'Wybór zakresu częstotliwości';
            app.ZakresczstotliwociLabel = uilabel(app.UIFigure);
            app.ZakresczstotliwociLabel.Text = 'Zakres częstotliwości';
            app.NumerpomiaruSliderLabel = uilabel(app.UIFigure);
            app.NumerpomiaruSliderLabel.Text = {'Numer'; 'pomiaru'};
            app.NumerpomiaruSlider = uislider(app.UIFigure);
            app.NumerpomiaruSlider.ValueChangedFcn = createCallbackFcn(app, @NumerpomiaruSliderValueChanged, true);
            app.ZapiszwynikdlawybranegopomiaruButton = uibutton(app.UIFigure, 'push');
            app.ZapiszwynikdlawybranegopomiaruButton.Text = {'Zapisz wynik'; 'dla wybranego pomiaru'};
            app.ZapiszwszystkoButton = uibutton(app.UIFigure, 'push');
            app.ZapiszwszystkoButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszwszystkoButtonPushed, true);
            app.ZapiszwszystkoButton.Text = 'Zapisz wszystko';
            app.MinSliderLabel = uilabel(app.UIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.UIFigure);
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MaxSliderLabel = uilabel(app.UIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.UIFigure);
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MinManual = uieditfield(app.UIFigure, 'numeric');
            app.MinManual.ValueChangedFcn = createCallbackFcn(app, @MinManualValueChanged, true);
            app.MaxManual = uieditfield(app.UIFigure, 'numeric');
            app.MaxManual.ValueChangedFcn = createCallbackFcn(app, @MaxManualValueChanged, true);
            app.WprowadgrnegraniceEditFieldLabel = uilabel(app.UIFigure);
            app.WprowadgrnegraniceEditFieldLabel.Text = 'Wprowadź górne granice';
            app.WprowadgrnegraniceEditField = uieditfield(app.UIFigure, 'text');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'algorytm genetyczny', 'algorytm gradientowy'};
            app.Switch.Value = 'algorytm genetyczny';
        end
    end

    methods (Access = public)

        % Construct app
        function app = nowe_gui_optymalizacja

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end